classdef plugin_manager_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PluginManagerUIFigure       matlab.ui.Figure
        ExportlibraryButton         matlab.ui.control.Button
        FilterviewEditField         matlab.ui.control.EditField
        FilterviewEditFieldLabel    matlab.ui.control.Label
        UnregisterlibraryButton     matlab.ui.control.Button
        RegisterlibraryButton       matlab.ui.control.Button
        CreatenewlibraryButton      matlab.ui.control.Button
        UnregisterselectedcomponentsButton  matlab.ui.control.Button
        RegisternewcomponentButton  matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        SysTab                      matlab.ui.container.Tab
        Panel                       matlab.ui.container.Panel
        SysTable                    matlab.ui.control.Table
        CtlTab                      matlab.ui.container.Tab
        Panel_2                     matlab.ui.container.Panel
        CtlTable                    matlab.ui.control.Table
        EstTab                      matlab.ui.container.Tab
        Panel_3                     matlab.ui.container.Panel
        EstTable                    matlab.ui.control.Table
        DistTab                     matlab.ui.container.Tab
        Panel_4                     matlab.ui.container.Panel
        DistTable                   matlab.ui.control.Table
    end

    
    properties (Access = private)
        LocalLib % Description
        TableData % Description
    end
    
    methods (Access = private)
        
        function results = load_tables(app)
           
            plugins = get_available_plugins();
            app.TableData.sys = app.struct_as_mat(plugins.sys);
            app.TableData.ctl = app.struct_as_mat(plugins.ctl);
            app.TableData.est = app.struct_as_mat(plugins.est);
            app.TableData.dist = app.struct_as_mat(plugins.dist);
            

            app.render_table();
        end

        function mat = struct_as_mat(app, str)
            mat = strings(length(str), 2);
            for i=1:length(str)
                mat(i, :) = [string(str(i).Name), string(str(i).Lib)];
            end
        end

        function app = add_table(app, t, new_table)
            if t == 1
                app.TableData.sys = [new_table; app.TableData.sys];
            elseif t == 2
                app.TableData.ctl = [new_table; app.TableData.ctl];
            elseif t == 3
                app.TableData.est = [new_table; app.TableData.est];
            elseif t == 4
                app.TableData.dist = [new_table; app.TableData.dist];
            end
        end


        function indices = exists_in_table(app, table, value)
            indices = zeros(height(table), 1);
            for j=1:height(table)
                if all(table(j, :) == value)
                    indices(j) = 1;
                    break
                end
            end
        end

        function app = remove_table(app, t, del_table)    
            
            function table = rm_table(app, table, del_table)
                indices_to_remove = zeros(height(table), 1);
                for i=1:height(del_table)
                    cur_t = del_table(i, :);
                    indices_to_remove = indices_to_remove ...
                        + app.exists_in_table(table, cur_t);
                end
                good_indices = indices_to_remove == 0;
                table = table(good_indices, :);
            end

            if t == 1
                app.TableData.sys = rm_table(app, app.TableData.sys, del_table);
            elseif t == 2
                app.TableData.ctl = rm_table(app, app.TableData.ctl, del_table);
            elseif t == 3
                app.TableData.est = rm_table(app, app.TableData.est, del_table);
            elseif t == 4
                app.TableData.dist = rm_table(app, app.TableData.dist, del_table);
            end

        end

        function render_table(app)
            app.SysTable.Data = app.TableData.sys;          
            app.CtlTable.Data = app.TableData.ctl;          
            app.EstTable.Data = app.TableData.est;          
            app.DistTable.Data = app.TableData.dist; 
        end
     
        
        function results = get_selected_items(app, table)
            selection = table.Selection;
            if ~isempty(selection)
                results = unique(selection(:, 1));
                return
            end
            results = [];
        end
        
        function [table, data, t] = get_active_table(app)
            tab = app.TabGroup.SelectedTab;
            if strcmp(tab.Title, 'Systems')
                t = 1;
                table = app.SysTable;
                data = app.TableData.sys;
            elseif strcmp(tab.Title, 'Controllers')
                t = 2;
                table = app.CtlTable;
                data = app.TableData.ctl;
            elseif strcmp(tab.Title, 'Estimators')
                t = 3;
                table = app.EstTable;
                data = app.TableData.est;
            elseif strcmp(tab.Title, 'Disturbances')
                t = 4;
                table = app.DistTable;
                data = app.TableData.dist;
            else 
                t = 0;
                table = [];
                data = [];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function on_start(app)
            app.LocalLib.handle = ...
                get_or_create_component_library(get_app_registry_path(), 'local');
            app.load_tables;

        end

        % Close request function: PluginManagerUIFigure
        function on_close(app, event)
            delete(app)
            
        end

        % Button down function: PluginManagerUIFigure
        function PluginManagerUIFigureButtonDown(app, event)
            
        end

        % Clicked callback: CtlTable, DistTable, SysTable
        function on_table_click(app, event)

            if isempty(event.Source.Selection)
                app.UnregisterselectedcomponentsButton.Enable = 0;
            else
                app.UnregisterselectedcomponentsButton.Enable = 1;
            end
        end

        % Button pushed function: RegisternewcomponentButton
        function on_comp_register(app, event)
            [file, location] = uigetfile({'*.m';'*.slx';'*.py';});
            if isempty(file)
                return
            end
            selpath = fullfile(location, file);
            
            if endsWith(file, '.slx')
                answer = inputdlg('Path', 'Component path');
                if isempty(answer)
                    return
                end
                selpath = strcat(selpath, ':', answer{1});
            end
            

            if is_valid_component(selpath)
                p = get_plugin_info(selpath);
                if any(app.exists_in_table(app.TableData.sys, [p.Name, app.LocalLib.handle.name]))
                    warning(strcat("Cannot add component '", p.Name, "'. Component already exists"));
                    return
                end
                register_component(p, p.T, app.LocalLib.handle.name);
                add_to_lib_manifest(p, p.T, app.LocalLib.handle.path)
                
                app.add_table(p.T, [p.Name, app.LocalLib.handle.name]);
                app.render_table()
            end
        end

        % Button pushed function: UnregisterselectedcomponentsButton
        function on_comp_unregister(app, event)
            [table, data, t] = app.get_active_table();
            items = app.get_selected_items(table);
            if length(items) > 1
                msg = 'Are you sure you want to delete multiple components?';
            elseif isscalar(items)
                sel = data(items, 1);
                lib = data(items, 2);
                msg = strcat("Are you sure you want to delete component '", ...
                    sel, "' from library '", lib, "'?");
            else
                return
            end
            c = uiconfirm(app.PluginManagerUIFigure, msg, 'Confirm delete', 'Options', ["Yes", "No"]);            
            if strcmp(c, "No")
                return
            end
            
            for i=1:length(items)
                name = data(items(i), 1);
                lib = data(items(i), 2);
                
                if ~strcmp(lib, 'local')
                    warning(strcat("Cannot remove component '", name, "'. ", ...
                        "Library is locked."));
                    continue
                end
                unregister_component(name, t, app.LocalLib.handle.name);
                remove_from_lib_manifest(name, t, app.LocalLib.handle.path);
                app.remove_table(t, [name, lib]);
                app.render_table();
            end
        end

        % Callback function: not associated with a component
        function on(app, event)
            
        end

        % Button pushed function: CreatenewlibraryButton
        function on_create_new_library(app, event)
            answer = inputdlg('Name', 'New library name');
            if isempty(answer)
                return
            end
            lib = get_or_create_component_library(get_app_registry_path(), answer{1});
        end

        % Button pushed function: RegisterlibraryButton
        function on_register_library(app, event)
            path = uigetdir;
            if isnumeric(path)
                return
            end
            register_component_library(path);
            copyfile(path, get_app_registry_path());
            app.load_tables;

        end

        % Button pushed function: UnregisterlibraryButton
        function on_unregister_library(app, event)
            function fn(selection)
                remove_component_library(selection); 
            end
            
            selector_app(list_component_libraries(1), @fn);
            app.load_tables;

        end

        % Button pushed function: ExportlibraryButton
        function on_export_library(app, event)
            function fn(selection)
                export_component_library(selection); 
            end       
            selector_app(list_component_libraries(1), @fn);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PluginManagerUIFigure = uifigure('Visible', 'off');
            app.PluginManagerUIFigure.Name = 'Plugin Manager';
            app.PluginManagerUIFigure.CloseRequestFcn = createCallbackFcn(app, @on_close, true);
            app.PluginManagerUIFigure.ButtonDownFcn = createCallbackFcn(app, @PluginManagerUIFigureButtonDown, true);
            app.TabGroup = uitabgroup(app.PluginManagerUIFigure);
            app.SysTab = uitab(app.TabGroup);
            app.SysTab.Title = 'Systems';
            app.Panel = uipanel(app.SysTab);
            app.SysTable = uitable(app.Panel);
            app.SysTable.ColumnName = {'Name'; 'Library'; 'Column 3'; 'Column 4'};
            app.SysTable.RowName = {};
            app.SysTable.ClickedFcn = createCallbackFcn(app, @on_table_click, true);
            app.CtlTab = uitab(app.TabGroup);
            app.CtlTab.Title = 'Controllers';
            app.Panel_2 = uipanel(app.CtlTab);
            app.CtlTable = uitable(app.Panel_2);
            app.CtlTable.ColumnName = {'Name'; 'Library'; 'Column 3'; 'Column 4'};
            app.CtlTable.RowName = {};
            app.CtlTable.ClickedFcn = createCallbackFcn(app, @on_table_click, true);
            app.EstTab = uitab(app.TabGroup);
            app.EstTab.Title = 'Estimators';
            app.Panel_3 = uipanel(app.EstTab);
            app.EstTable = uitable(app.Panel_3);
            app.EstTable.ColumnName = {'Name'; 'Library'; 'Column 3'; 'Column 4'};
            app.EstTable.RowName = {};
            app.DistTab = uitab(app.TabGroup);
            app.DistTab.Title = 'Disturbances';
            app.Panel_4 = uipanel(app.DistTab);
            app.DistTable = uitable(app.Panel_4);
            app.DistTable.ColumnName = {'Name'; 'Library'; 'Column 3'; 'Column 4'};
            app.DistTable.RowName = {};
            app.DistTable.ClickedFcn = createCallbackFcn(app, @on_table_click, true);
            app.RegisternewcomponentButton = uibutton(app.PluginManagerUIFigure, 'push');
            app.RegisternewcomponentButton.ButtonPushedFcn = createCallbackFcn(app, @on_comp_register, true);
            app.RegisternewcomponentButton.Text = {'Register new'; 'component'};
            app.UnregisterselectedcomponentsButton = uibutton(app.PluginManagerUIFigure, 'push');
            app.UnregisterselectedcomponentsButton.ButtonPushedFcn = createCallbackFcn(app, @on_comp_unregister, true);
            app.UnregisterselectedcomponentsButton.Enable = 'off';
            app.UnregisterselectedcomponentsButton.Text = {'Unregister selected '; 'components'};
            app.CreatenewlibraryButton = uibutton(app.PluginManagerUIFigure, 'push');
            app.CreatenewlibraryButton.ButtonPushedFcn = createCallbackFcn(app, @on_create_new_library, true);
            app.CreatenewlibraryButton.Text = 'Create new library';
            app.RegisterlibraryButton = uibutton(app.PluginManagerUIFigure, 'push');
            app.RegisterlibraryButton.ButtonPushedFcn = createCallbackFcn(app, @on_register_library, true);
            app.RegisterlibraryButton.Text = 'Register library';
            app.UnregisterlibraryButton = uibutton(app.PluginManagerUIFigure, 'push');
            app.UnregisterlibraryButton.ButtonPushedFcn = createCallbackFcn(app, @on_unregister_library, true);
            app.UnregisterlibraryButton.Text = 'Unregister library';
            app.FilterviewEditFieldLabel = uilabel(app.PluginManagerUIFigure);
            app.FilterviewEditFieldLabel.Text = 'Filter view';
            app.FilterviewEditField = uieditfield(app.PluginManagerUIFigure, 'text');
            app.ExportlibraryButton = uibutton(app.PluginManagerUIFigure, 'push');
            app.ExportlibraryButton.ButtonPushedFcn = createCallbackFcn(app, @on_export_library, true);
            app.ExportlibraryButton.Text = 'Export library';
            % Show the figure after all components are created
            app.PluginManagerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plugin_manager_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PluginManagerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @on_start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PluginManagerUIFigure)
        end
    end
end